classdef GUI_Version_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        runsimulationButton          matlab.ui.control.Button
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        FinalCoordiantesLabel        matlab.ui.control.Label
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        MotorsAngleLabel             matlab.ui.control.Label
        Motor1EditFieldLabel         matlab.ui.control.Label
        Motor1EditField              matlab.ui.control.NumericEditField
        Motor2EditFieldLabel         matlab.ui.control.Label
        Motor2EditField              matlab.ui.control.NumericEditField
        Motor3EditFieldLabel         matlab.ui.control.Label
        Motor3EditField              matlab.ui.control.NumericEditField
        RunTriFingerButton           matlab.ui.control.Button
        TriFingerSimulationAppLabel  matlab.ui.control.Label
        the3dplot                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function startupFcn(app)
            t=1;
            [x1,y1,z1,x2,y2,z2,n,a1,a2,a3]= Plot_Simulation(X,Y,Z,theta);
            plot3(app.the3dplot,0,0,0,'or')
            hold(app.the3dplot,"on");
            plot3(app.the3dplot,[0,10],[0,0],[0,0],'LineWidth',4)
            plot3(app.the3dplot,x2(n),y2(n),z2(n))
            plot3(app.the3dplot,[0,x1(t)],[0,y1(t)],[0,z1(t)],'r','LineWidth',4)
            plot3(app.the3dplot,[x1(t),x2(t)],[y1(t),y2(t)],[z1(t),z2(t)],'b','LineWidth',4)           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runsimulationButton
        function runsimulationButtonPushed(app, event)
            % getting value from GUI
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;   
            % calculate
            theta = Inverse_Kinematic(X,Y,Z);
%             disp(theta)
%             app.Motor1EditField.Value = theta(1);
%             app.Motor2EditField.Value = theta(2);
%             app.Motor3EditField.Value = theta(3);
%             [xl1,yl1,xAdd,yAdd,xf2,yf2] = plot_2d(X,Y,Z);
%             %2D plot
%             plot(app.the2dplot,0,0,'o',-xf2,-yf2,'*','LineWidth',4)
%             hold(app.the2dplot,"on")
%             plot(app.the2dplot,[0,xl1],[0,-yl1],'r','LineWidth',4)
%             plot(app.the2dplot,[xl1,xAdd],[-yl1,yAdd],'b','LineWidth',4)
%             app.the2dplot.XGrid = 'on';
%             app.the2dplot.YGrid = 'on';
%             app.the2dplot.Box = 'on';
%             hold(app.the2dplot,"off");
            
            %3D - plot
            t=1;
            [x1,y1,z1,x2,y2,z2,n,a1,a2,a3]= Plot_Simulation(X,Y,Z,theta);
                plot3(app.the3dplot,0,0,0,'or')
                hold(app.the3dplot,"on");
                plot3(app.the3dplot,[0,10],[0,0],[0,0],'LineWidth',4)
                plot3(app.the3dplot,x2(n),y2(n),z2(n))
                plot3(app.the3dplot,[0,x1(t)],[0,y1(t)],[0,z1(t)],'r','LineWidth',4)
                plot3(app.the3dplot,[x1(t),x2(t)],[y1(t),y2(t)],[z1(t),z2(t)],'b','LineWidth',4)
            for t = 1:n
                plot3(app.the3dplot,0,0,0,'or')
                hold(app.the3dplot,"on");
                plot3(app.the3dplot,[0,10],[0,0],[0,0],'LineWidth',4)
                plot3(app.the3dplot,x2(n),y2(n),z2(n))
                plot3(app.the3dplot,[0,x1(t)],[0,y1(t)],[0,z1(t)],'r','LineWidth',4)
                plot3(app.the3dplot,[x1(t),x2(t)],[y1(t),y2(t)],[z1(t),z2(t)],'b','LineWidth',4)
                app.the3dplot.XGrid = 'on';
                app.the3dplot.YGrid = 'on';
                app.the3dplot.Box = 'on';
                hold(app.the3dplot,"off");
                app.Motor1EditField.Value = a1(t)*180/pi;
                app.Motor2EditField.Value = a2(t)*180/pi;
                app.Motor3EditField.Value = a3(t)*180/pi;
                pause(0.02)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.runsimulationButton = uibutton(app.UIFigure, 'push');
            app.runsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @runsimulationButtonPushed, true);
            app.runsimulationButton.Text = 'run simulation';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 3;
            app.FinalCoordiantesLabel = uilabel(app.UIFigure);
            app.FinalCoordiantesLabel.Text = 'Final Coordiantes: ';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Value = 5;
            app.MotorsAngleLabel = uilabel(app.UIFigure);
            app.MotorsAngleLabel.Text = 'Motors Angle : ';
            app.Motor1EditFieldLabel = uilabel(app.UIFigure);
            app.Motor1EditFieldLabel.Text = 'Motor 1';
            app.Motor1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Motor2EditFieldLabel = uilabel(app.UIFigure);
            app.Motor2EditFieldLabel.Text = 'Motor 2';
            app.Motor2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Motor3EditFieldLabel = uilabel(app.UIFigure);
            app.Motor3EditFieldLabel.Text = 'Motor 3';
            app.Motor3EditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTriFingerButton = uibutton(app.UIFigure, 'push');
            app.RunTriFingerButton.Text = 'Run TriFinger';
            app.TriFingerSimulationAppLabel = uilabel(app.UIFigure);
            app.TriFingerSimulationAppLabel.Text = 'TriFinger Simulation App';
            app.the3dplot = uiaxes(app.UIFigure);
            title(app.the3dplot, 'THE PLOT')
            xlabel(app.the3dplot, 'X')
            ylabel(app.the3dplot, 'Y')
            zlabel(app.the3dplot, 'Z')
            app.the3dplot.XLim = [-15 15];
            app.the3dplot.YLim = [-15 15];
            app.the3dplot.ZLim = [-15 0];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Version_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end